classdef App_Forward_Kinematics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        IKLabel               matlab.ui.control.Label
        EndEffectorPoseLabel  matlab.ui.control.Label
        UITable               matlab.ui.control.Table
        TabGroup              matlab.ui.container.TabGroup
        CartesianSpaceTab     matlab.ui.container.Tab
        RollzSpinner          matlab.ui.control.Spinner
        RollzSpinnerLabel     matlab.ui.control.Label
        PitchySpinner         matlab.ui.control.Spinner
        PitchySpinnerLabel    matlab.ui.control.Label
        YawxSpinner           matlab.ui.control.Spinner
        YawxSpinnerLabel      matlab.ui.control.Label
        zSpinner              matlab.ui.control.Spinner
        zSpinnerLabel         matlab.ui.control.Label
        ySpinner              matlab.ui.control.Spinner
        ySpinnerLabel         matlab.ui.control.Label
        xSpinner              matlab.ui.control.Spinner
        xSpinnerLabel         matlab.ui.control.Label
        JointspaceTab         matlab.ui.container.Tab
        JointRzSpinner        matlab.ui.control.Spinner
        JointRzSpinnerLabel   matlab.ui.control.Label
        JointRySpinner        matlab.ui.control.Spinner
        JointRySpinnerLabel   matlab.ui.control.Label
        JointRxSpinner        matlab.ui.control.Spinner
        JointRxSpinnerLabel   matlab.ui.control.Label
        JointzSpinner         matlab.ui.control.Spinner
        JointzSpinnerLabel    matlab.ui.control.Label
        JointySpinner         matlab.ui.control.Spinner
        JointySpinnerLabel    matlab.ui.control.Label
        JointxSpinner         matlab.ui.control.Spinner
        JointxLabel           matlab.ui.control.Label
        FK_Panel              matlab.ui.container.Panel
        Theta6Spinner         matlab.ui.control.Spinner
        Theta6SpinnerLabel    matlab.ui.control.Label
        Theta5Spinner         matlab.ui.control.Spinner
        Theta5SpinnerLabel    matlab.ui.control.Label
        Theta4Spinner         matlab.ui.control.Spinner
        Theta4SpinnerLabel    matlab.ui.control.Label
        Theta3Spinner         matlab.ui.control.Spinner
        Theta3SpinnerLabel    matlab.ui.control.Label
        Theta2Spinner         matlab.ui.control.Spinner
        Theta2SpinnerLabel    matlab.ui.control.Label
        Theta1Spinner         matlab.ui.control.Spinner
        Theta1SpinnerLabel    matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Tum app icerisinde tanimli olmasi icin property olarak tanimlanir
        ax;
        world; % vrworld parametrelerine erisebilmek icin
        T_0_ee;
        th;
    end
    
    methods (Access = private)
        
        function updateVR(app)
            % VR ekrani uzerindeki jointlerin acilarini gunceller
            joints = ["Joint1_Transform","Joint2_Transform",...
                      "Joint3_Transform","Joint4_Transform",...
                      "Joint5_Transform","Joint6_Transform"];
            app.world = setfield(app.world,joints(1),'rotation',[0 1 0 app.th(1)]);
            app.world = setfield(app.world,joints(2),'rotation',[0 1 0 -app.th(2)]);
            app.world = setfield(app.world,joints(3),'rotation',[0 1 0 app.th(3)]);
            app.world = setfield(app.world,joints(4),'rotation',[0 1 0 -app.th(4)]);
            app.world = setfield(app.world,joints(5),'rotation',[0 1 0 -app.th(5)]);
            app.world = setfield(app.world,joints(6),'rotation',[0 1 0 -app.th(6)]);
            % + - olmasi vrworld ekranda dogru yonde donmenin gosterilmesi ile ilgili
        end
        
        function updateIKSpinners(app)
            % Ters K. spinnerlari o anki T_0_ee gunceller
            set(app.xSpinner, 'Value', round(app.T_0_ee(1,4),3));
            set(app.ySpinner, 'Value', round(app.T_0_ee(2,4),3));
            set(app.zSpinner, 'Value', round(app.T_0_ee(3,4),3));

        end
        
        function updateFKSpinners(app)
            % Ileri K. spinnnerlari o anki th degerleri gore gunceller
            set(app.Theta1Spinner, 'Value', round(rad2deg(app.th(1)),2));
            set(app.Theta2Spinner, 'Value', round(rad2deg(app.th(2)),2));
            set(app.Theta3Spinner, 'Value', round(rad2deg(app.th(3)),2));
            set(app.Theta4Spinner, 'Value', round(rad2deg(app.th(4)),2));
            set(app.Theta5Spinner, 'Value', round(rad2deg(app.th(5)),2));
            set(app.Theta6Spinner, 'Value', round(rad2deg(app.th(6)),2));
        end
        
        function checkIKSolution(app, IK_in)
            % Ters K. cozumun hangisinin gerceklestirilebilir olmasina bakar
            % Bunu Ters K. cozumleri icerisinde o anki th'dan en az fark
            % olacak sekilde bulur, fark eger pi/9'den fazla ise cozum
            % olarak kabul etmez ve th guncellenmez
            
            app.th(2) = app.th(2) - pi/2;
            diffMatrix = abs(IK_in - app.th);
            S = sum(diffMatrix,2);
            index = S == min(S);
            if max(abs(IK_in(index,:) - app.th)) < pi/9
                app.th = IK_in(index,:);
                app.IKLabel.Text = "IK solution found";
            else
                app.IKLabel.Text = "No IK solution found";

            end
            app.th(2) = app.th(2) + pi/2;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.th = zeros(1,6);
            app.ax = app.UIAxes;

            [app.T_0_ee, T] = forwardKinematics(app.th);
            showRobot(app.ax, T);
            
            app.world = vrworld('Robot_World.WRL');
            open(app.world);
            myCanvas = vr.canvas(app.world);
            updateVR(app);
            movegui('east'); movegui(app.UIFigure,[100 100]);
            set(app.UITable, 'Data', app.T_0_ee);

            updateIKSpinners(app);
        end

        % Value changing function: Theta1Spinner
        function Theta1SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.th(1) = 2*pi/360*changingValue;

            updateVR(app);
            [app.T_0_ee, T] = forwardKinematics(app.th);
            set(app.UITable, 'Data', app.T_0_ee);
            showRobot(app.ax, T);
            updateIKSpinners(app);
        end

        % Value changing function: Theta2Spinner
        function Theta2SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.th(2) = 2*pi/360*changingValue;
            
            updateVR(app);
            [app.T_0_ee, T] = forwardKinematics(app.th);
            set(app.UITable, 'Data', app.T_0_ee);
            showRobot(app.ax, T);
            updateIKSpinners(app);
        end

        % Value changing function: Theta3Spinner
        function Theta3SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.th(3) = 2*pi/360*changingValue;

            updateVR(app);
            [app.T_0_ee, T] = forwardKinematics(app.th);
            set(app.UITable, 'Data', app.T_0_ee);
            showRobot(app.ax, T);
            updateIKSpinners(app);
        end

        % Value changing function: Theta4Spinner
        function Theta4SpinnerValueChanging(app, event)
            changingValue = event.Value; 
            app.th(4) = 2*pi/360*changingValue;

            updateVR(app);
            [app.T_0_ee, T] = forwardKinematics(app.th);
            set(app.UITable, 'Data', app.T_0_ee);
            showRobot(app.ax, T);
            updateIKSpinners(app);
        end

        % Value changing function: Theta5Spinner
        function Theta5SpinnerValueChanging(app, event)
            changingValue = event.Value;     
            app.th(5) = 2*pi/360*changingValue;

            updateVR(app);
            [app.T_0_ee, T] = forwardKinematics(app.th);
            set(app.UITable, 'Data', app.T_0_ee);
            showRobot(app.ax, T);
            updateIKSpinners(app);
        end

        % Value changing function: Theta6Spinner
        function Theta6SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.th(6) = 2*pi/360*changingValue;

            updateVR(app);
            [app.T_0_ee, T] = forwardKinematics(app.th);
            set(app.UITable, 'Data', app.T_0_ee);
            showRobot(app.ax, T);
            updateIKSpinners(app);
        end

        % Value changing function: xSpinner
        function xSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.T_0_ee(1,4) = changingValue;

            IK = robotIK(app.T_0_ee);
            checkIKSolution(app, IK);

            [app.T_0_ee, T] = forwardKinematics(app.th);
            set(app.UITable, 'Data', app.T_0_ee);
            showRobot(app.ax, T);
            updateFKSpinners(app);
            updateVR(app);
        end

        % Value changing function: ySpinner
        function ySpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.T_0_ee(2,4) = changingValue;

            IK = robotIK(app.T_0_ee);
            checkIKSolution(app, IK);

            [app.T_0_ee, T] = forwardKinematics(app.th);
            set(app.UITable, 'Data', app.T_0_ee);
            showRobot(app.ax, T);
            updateFKSpinners(app);
            updateVR(app);
        end

        % Value changing function: zSpinner
        function zSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.T_0_ee(3,4) = changingValue;

            IK = robotIK(app.T_0_ee);
            checkIKSolution(app, IK);

            [app.T_0_ee, T] = forwardKinematics(app.th);
            set(app.UITable, 'Data', app.T_0_ee);
            showRobot(app.ax, T);
            updateFKSpinners(app);
            updateVR(app);
        end

        % Value changing function: YawxSpinner
        function YawxSpinnerValueChanging(app, event)
            changingValue = event.Value;
            changingValue = deg2rad(changingValue);
            
            T = eul2rotm([0 0 changingValue]);

            app.T_0_ee(1:3,1:3) =  T*app.T_0_ee(1:3,1:3);

            IK = robotIK(app.T_0_ee);
            checkIKSolution(app, IK);

            [app.T_0_ee, T] = forwardKinematics(app.th);
            set(app.UITable, 'Data', app.T_0_ee);
            showRobot(app.ax, T);
            updateFKSpinners(app);
            updateVR(app);
        end

        % Value changing function: PitchySpinner
        function PitchySpinnerValueChanging(app, event)
            changingValue = event.Value;
            changingValue = deg2rad(changingValue);
            
            T = eul2rotm([0 changingValue 0]);

            app.T_0_ee(1:3,1:3) =  T*app.T_0_ee(1:3,1:3);

            IK = robotIK(app.T_0_ee);
            checkIKSolution(app, IK);

            [app.T_0_ee, T] = forwardKinematics(app.th);
            set(app.UITable, 'Data', app.T_0_ee);
            showRobot(app.ax, T);
            updateFKSpinners(app);
            updateVR(app);
        end

        % Value changing function: RollzSpinner
        function RollzSpinnerValueChanging(app, event)
            changingValue = event.Value;
            changingValue = deg2rad(changingValue);
            
            T = eul2rotm([changingValue 0 0]);

            app.T_0_ee(1:3,1:3) =  T*app.T_0_ee(1:3,1:3);

            IK = robotIK(app.T_0_ee);
            checkIKSolution(app, IK);

            [app.T_0_ee, T] = forwardKinematics(app.th);
            set(app.UITable, 'Data', app.T_0_ee);
            showRobot(app.ax, T);
            updateFKSpinners(app);
            updateVR(app);
        end

        % Value changed function: YawxSpinner
        function YawxSpinnerValueChanged(app, event)
            set(app.YawxSpinner,'Value',0);
        end

        % Value changed function: PitchySpinner
        function PitchySpinnerValueChanged(app, event)
            set(app.PitchySpinner,'Value',0);
        end

        % Value changed function: RollzSpinner
        function RollzSpinnerValueChanged(app, event)
            set(app.RollzSpinner,'Value',0); 
        end

        % Value changing function: JointRxSpinner
        function JointRxSpinnerValueChanging(app, event)
            changingValue = event.Value;
            changingValue = deg2rad(changingValue);
            
            T = eul2rotm([0 0 changingValue]);

            app.T_0_ee(1:3,1:3) =  app.T_0_ee(1:3,1:3)*T;

            IK = robotIK(app.T_0_ee);
            checkIKSolution(app, IK);

            [app.T_0_ee, T] = forwardKinematics(app.th);
            set(app.UITable, 'Data', app.T_0_ee);
            showRobot(app.ax, T);
            updateFKSpinners(app);
            updateVR(app);
        end

        % Value changing function: JointRySpinner
        function JointRySpinnerValueChanging(app, event)
            changingValue = event.Value;
            changingValue = deg2rad(changingValue);
            
            T = eul2rotm([0 changingValue 0]);

            app.T_0_ee(1:3,1:3) =  app.T_0_ee(1:3,1:3)*T;

            IK = robotIK(app.T_0_ee);
            checkIKSolution(app, IK);

            [app.T_0_ee, T] = forwardKinematics(app.th);
            set(app.UITable, 'Data', app.T_0_ee);
            showRobot(app.ax, T);
            updateFKSpinners(app);
            updateVR(app);
        end

        % Value changing function: JointRzSpinner
        function JointRzSpinnerValueChanging(app, event)
            changingValue = event.Value;
            changingValue = deg2rad(changingValue);
            
            T = eul2rotm([changingValue 0 0]);

            app.T_0_ee(1:3,1:3) =  app.T_0_ee(1:3,1:3)*T;

            IK = robotIK(app.T_0_ee);
            checkIKSolution(app, IK);

            [app.T_0_ee, T] = forwardKinematics(app.th);
            set(app.UITable, 'Data', app.T_0_ee);
            showRobot(app.ax, T);
            updateFKSpinners(app);
            updateVR(app);
        end

        % Value changed function: JointRxSpinner
        function JointRxSpinnerValueChanged(app, event)
            set(app.JointRxSpinner,'Value',0);
        end

        % Value changed function: JointRySpinner
        function JointRySpinnerValueChanged(app, event)
            set(app.JointRySpinner,'Value',0);
        end

        % Value changed function: JointRzSpinner
        function JointRzSpinnerValueChanged(app, event)
            set(app.JointRzSpinner,'Value',0);
        end

        % Value changing function: JointxSpinner
        function JointxSpinnerValueChanging(app, event)
            changingValue = event.Value;
            Tpos = trvec2tform([changingValue, 0, 0]);
            app.T_0_ee = app.T_0_ee*Tpos;
            
            IK = robotIK(app.T_0_ee);
            checkIKSolution(app, IK);

            [app.T_0_ee, T] = forwardKinematics(app.th);
            set(app.UITable, 'Data', app.T_0_ee);
            showRobot(app.ax, T);
            updateFKSpinners(app);
            updateVR(app);
        end

        % Value changing function: JointySpinner
        function JointySpinnerValueChanging(app, event)
            changingValue = event.Value;
            Tpos = trvec2tform([0, changingValue, 0]);
            app.T_0_ee = app.T_0_ee*Tpos;
            
            IK = robotIK(app.T_0_ee);
            checkIKSolution(app, IK);

            [app.T_0_ee, T] = forwardKinematics(app.th);
            set(app.UITable, 'Data', app.T_0_ee);
            showRobot(app.ax, T);
            updateFKSpinners(app);
            updateVR(app);
        end

        % Value changing function: JointzSpinner
        function JointzSpinnerValueChanging(app, event)
            changingValue = event.Value;
            Tpos = trvec2tform([0, 0, changingValue]);
            app.T_0_ee = app.T_0_ee*Tpos;
            
            IK = robotIK(app.T_0_ee);
            checkIKSolution(app, IK);

            [app.T_0_ee, T] = forwardKinematics(app.th);
            set(app.UITable, 'Data', app.T_0_ee);
            showRobot(app.ax, T);
            updateFKSpinners(app);
            updateVR(app);
        end

        % Value changed function: JointxSpinner
        function JointxSpinnerValueChanged(app, event)
            set(app.JointxSpinner,'Value',0);
            
        end

        % Value changed function: JointySpinner
        function JointySpinnerValueChanged(app, event)
            set(app.JointySpinner,'Value',0);
            
        end

        % Value changed function: JointzSpinner
        function JointzSpinnerValueChanged(app, event)
            set(app.JointzSpinner,'Value',0);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FK_Panel = uipanel(app.UIFigure);
            app.FK_Panel.Title = 'Forward Kinematics';
            app.Theta1SpinnerLabel = uilabel(app.FK_Panel);
            app.Theta1SpinnerLabel.Text = 'Theta 1';
            app.Theta1Spinner = uispinner(app.FK_Panel);
            app.Theta1Spinner.ValueChangingFcn = createCallbackFcn(app, @Theta1SpinnerValueChanging, true);
            app.Theta1Spinner.Limits = [-360 360];
            app.Theta2SpinnerLabel = uilabel(app.FK_Panel);
            app.Theta2SpinnerLabel.Text = 'Theta 2';
            app.Theta2Spinner = uispinner(app.FK_Panel);
            app.Theta2Spinner.ValueChangingFcn = createCallbackFcn(app, @Theta2SpinnerValueChanging, true);
            app.Theta2Spinner.Limits = [-360 360];
            app.Theta3SpinnerLabel = uilabel(app.FK_Panel);
            app.Theta3SpinnerLabel.Text = 'Theta 3';
            app.Theta3Spinner = uispinner(app.FK_Panel);
            app.Theta3Spinner.ValueChangingFcn = createCallbackFcn(app, @Theta3SpinnerValueChanging, true);
            app.Theta3Spinner.Limits = [-360 360];
            app.Theta4SpinnerLabel = uilabel(app.FK_Panel);
            app.Theta4SpinnerLabel.Text = 'Theta 4';
            app.Theta4Spinner = uispinner(app.FK_Panel);
            app.Theta4Spinner.ValueChangingFcn = createCallbackFcn(app, @Theta4SpinnerValueChanging, true);
            app.Theta4Spinner.Limits = [-360 360];
            app.Theta5SpinnerLabel = uilabel(app.FK_Panel);
            app.Theta5SpinnerLabel.Text = 'Theta 5';
            app.Theta5Spinner = uispinner(app.FK_Panel);
            app.Theta5Spinner.ValueChangingFcn = createCallbackFcn(app, @Theta5SpinnerValueChanging, true);
            app.Theta5Spinner.Limits = [-360 360];
            app.Theta6SpinnerLabel = uilabel(app.FK_Panel);
            app.Theta6SpinnerLabel.Text = 'Theta 6';
            app.Theta6Spinner = uispinner(app.FK_Panel);
            app.Theta6Spinner.ValueChangingFcn = createCallbackFcn(app, @Theta6SpinnerValueChanging, true);
            app.Theta6Spinner.Limits = [-360 360];
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CartesianSpaceTab = uitab(app.TabGroup);
            app.CartesianSpaceTab.Title = 'Cartesian Space';
            app.xSpinnerLabel = uilabel(app.CartesianSpaceTab);
            app.xSpinnerLabel.Text = 'x';
            app.xSpinner = uispinner(app.CartesianSpaceTab);
            app.xSpinner.Step = 0.005;
            app.xSpinner.ValueChangingFcn = createCallbackFcn(app, @xSpinnerValueChanging, true);
            app.ySpinnerLabel = uilabel(app.CartesianSpaceTab);
            app.ySpinnerLabel.Text = 'y';
            app.ySpinner = uispinner(app.CartesianSpaceTab);
            app.ySpinner.Step = 0.005;
            app.ySpinner.ValueChangingFcn = createCallbackFcn(app, @ySpinnerValueChanging, true);
            app.zSpinnerLabel = uilabel(app.CartesianSpaceTab);
            app.zSpinnerLabel.Text = 'z';
            app.zSpinner = uispinner(app.CartesianSpaceTab);
            app.zSpinner.Step = 0.005;
            app.zSpinner.ValueChangingFcn = createCallbackFcn(app, @zSpinnerValueChanging, true);
            app.YawxSpinnerLabel = uilabel(app.CartesianSpaceTab);
            app.YawxSpinnerLabel.Text = 'Yaw x';
            app.YawxSpinner = uispinner(app.CartesianSpaceTab);
            app.YawxSpinner.Step = 0.5;
            app.YawxSpinner.ValueChangingFcn = createCallbackFcn(app, @YawxSpinnerValueChanging, true);
            app.YawxSpinner.ValueChangedFcn = createCallbackFcn(app, @YawxSpinnerValueChanged, true);
            app.PitchySpinnerLabel = uilabel(app.CartesianSpaceTab);
            app.PitchySpinnerLabel.Text = 'Pitch y';
            app.PitchySpinner = uispinner(app.CartesianSpaceTab);
            app.PitchySpinner.Step = 0.5;
            app.PitchySpinner.ValueChangingFcn = createCallbackFcn(app, @PitchySpinnerValueChanging, true);
            app.PitchySpinner.ValueChangedFcn = createCallbackFcn(app, @PitchySpinnerValueChanged, true);
            app.RollzSpinnerLabel = uilabel(app.CartesianSpaceTab);
            app.RollzSpinnerLabel.Text = 'Roll z';
            app.RollzSpinner = uispinner(app.CartesianSpaceTab);
            app.RollzSpinner.Step = 0.5;
            app.RollzSpinner.ValueChangingFcn = createCallbackFcn(app, @RollzSpinnerValueChanging, true);
            app.RollzSpinner.ValueChangedFcn = createCallbackFcn(app, @RollzSpinnerValueChanged, true);
            app.JointspaceTab = uitab(app.TabGroup);
            app.JointspaceTab.Title = 'Joint space';
            app.JointxLabel = uilabel(app.JointspaceTab);
            app.JointxLabel.Text = 'Joint x';
            app.JointxSpinner = uispinner(app.JointspaceTab);
            app.JointxSpinner.Step = 0.001;
            app.JointxSpinner.ValueChangingFcn = createCallbackFcn(app, @JointxSpinnerValueChanging, true);
            app.JointxSpinner.ValueChangedFcn = createCallbackFcn(app, @JointxSpinnerValueChanged, true);
            app.JointySpinnerLabel = uilabel(app.JointspaceTab);
            app.JointySpinnerLabel.Text = 'Joint y';
            app.JointySpinner = uispinner(app.JointspaceTab);
            app.JointySpinner.Step = 0.001;
            app.JointySpinner.ValueChangingFcn = createCallbackFcn(app, @JointySpinnerValueChanging, true);
            app.JointySpinner.ValueChangedFcn = createCallbackFcn(app, @JointySpinnerValueChanged, true);
            app.JointzSpinnerLabel = uilabel(app.JointspaceTab);
            app.JointzSpinnerLabel.Text = 'Joint z';
            app.JointzSpinner = uispinner(app.JointspaceTab);
            app.JointzSpinner.Step = 0.001;
            app.JointzSpinner.ValueChangingFcn = createCallbackFcn(app, @JointzSpinnerValueChanging, true);
            app.JointzSpinner.ValueChangedFcn = createCallbackFcn(app, @JointzSpinnerValueChanged, true);
            app.JointRxSpinnerLabel = uilabel(app.JointspaceTab);
            app.JointRxSpinnerLabel.Text = 'Joint Rx';
            app.JointRxSpinner = uispinner(app.JointspaceTab);
            app.JointRxSpinner.Step = 0.1;
            app.JointRxSpinner.ValueChangingFcn = createCallbackFcn(app, @JointRxSpinnerValueChanging, true);
            app.JointRxSpinner.ValueChangedFcn = createCallbackFcn(app, @JointRxSpinnerValueChanged, true);
            app.JointRySpinnerLabel = uilabel(app.JointspaceTab);
            app.JointRySpinnerLabel.Text = 'Joint Ry';
            app.JointRySpinner = uispinner(app.JointspaceTab);
            app.JointRySpinner.Step = 0.1;
            app.JointRySpinner.ValueChangingFcn = createCallbackFcn(app, @JointRySpinnerValueChanging, true);
            app.JointRySpinner.ValueChangedFcn = createCallbackFcn(app, @JointRySpinnerValueChanged, true);
            app.JointRzSpinnerLabel = uilabel(app.JointspaceTab);
            app.JointRzSpinnerLabel.Text = 'Joint Rz';
            app.JointRzSpinner = uispinner(app.JointspaceTab);
            app.JointRzSpinner.Step = 0.1;
            app.JointRzSpinner.ValueChangingFcn = createCallbackFcn(app, @JointRzSpinnerValueChanging, true);
            app.JointRzSpinner.ValueChangedFcn = createCallbackFcn(app, @JointRzSpinnerValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.EndEffectorPoseLabel = uilabel(app.UIFigure);
            app.EndEffectorPoseLabel.Text = 'End Effector Pose';
            app.IKLabel = uilabel(app.UIFigure);
            app.IKLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Forward_Kinematics

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end