classdef App_Kinematics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        SolutionLabel        matlab.ui.control.Label
        PoseLabel            matlab.ui.control.Label
        PoseTable            matlab.ui.control.Table
        TabGroup             matlab.ui.container.TabGroup
        CartesianSpace       matlab.ui.container.Tab
        RzCartesian          matlab.ui.control.Spinner
        RollzSpinnerLabel    matlab.ui.control.Label
        RyCartesian          matlab.ui.control.Spinner
        PitchySpinnerLabel   matlab.ui.control.Label
        RxCartesian          matlab.ui.control.Spinner
        YawxSpinnerLabel     matlab.ui.control.Label
        zCartesian           matlab.ui.control.Spinner
        zSpinnerLabel        matlab.ui.control.Label
        yCartesian           matlab.ui.control.Spinner
        ySpinnerLabel        matlab.ui.control.Label
        xCartesian           matlab.ui.control.Spinner
        xSpinnerLabel        matlab.ui.control.Label
        JointSpace           matlab.ui.container.Tab
        RzJoint              matlab.ui.control.Spinner
        JointRzSpinnerLabel  matlab.ui.control.Label
        RyJoint              matlab.ui.control.Spinner
        JointRySpinnerLabel  matlab.ui.control.Label
        RxJoint              matlab.ui.control.Spinner
        JointRxSpinnerLabel  matlab.ui.control.Label
        zJoint               matlab.ui.control.Spinner
        JointzSpinnerLabel   matlab.ui.control.Label
        yJoint               matlab.ui.control.Spinner
        JointySpinnerLabel   matlab.ui.control.Label
        xJoint               matlab.ui.control.Spinner
        JointxLabel          matlab.ui.control.Label
        FK_Panel             matlab.ui.container.Panel
        Theta6               matlab.ui.control.Spinner
        Theta6SpinnerLabel   matlab.ui.control.Label
        Theta5               matlab.ui.control.Spinner
        Theta5SpinnerLabel   matlab.ui.control.Label
        Theta4               matlab.ui.control.Spinner
        Theta4SpinnerLabel   matlab.ui.control.Label
        Theta3               matlab.ui.control.Spinner
        Theta3SpinnerLabel   matlab.ui.control.Label
        Theta2               matlab.ui.control.Spinner
        Theta2SpinnerLabel   matlab.ui.control.Label
        Theta1               matlab.ui.control.Spinner
        Theta1SpinnerLabel   matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % Tum app icerisinde tanimli olmasi icin property olarak tanimlanir

        ax;      % plot ekrani axes
        world;   % vrworld parametrelerine erisebilmek icin
        eePose;  % End Effector base'ye gore transformasyon matrisi
        th;      % robotun eklemlerinin th degerleri
    end

    methods (Access = private)
        
        function [T_0_ee, T_0_N] = forwardKinematics(~, theta)
            %forwardKinematics  Belirtilen 6R robot icin ileri kinematik,
            %                   MDH parametreleri sabit, daha sonra
            %                   degistirme ozelligi eklenecektir
            %
            %   [T_0_6, T_0_N] = forwardKinematics(th)
            %
            %   T_0_N 3 boyutlu matris: T(:,:,N) = T_0_N

            L1 = 0.1;
            L2 = 0.3;
            L3 = 0.2;
            L4 = 0.06;

            %    |-| alpha_i-1 |-| a_i-1 |-| d_i |-| th_i |-|
            MDH = [0 0 L1 theta(1); -pi/2 0 0 -pi/2 + theta(2); 0 L2 0 theta(3);
                -pi/2 0 L3 theta(4); pi/2 0 0 theta(5); -pi/2 0 0 theta(6); 0 0 L4 0];

            T_0_ee = eye(4);
            T_0_N = zeros(4,4,7);

            for i = 1:7
                T_0_ee = T_0_ee * [cos(MDH(i,4)) -sin(MDH(i,4)) 0 MDH(i,2);
                    sin(MDH(i,4))*cos(MDH(i,1)) cos(MDH(i,4))*cos(MDH(i,1)) -sin(MDH(i,1)) -sin(MDH(i,1))*MDH(i,3);
                    sin(MDH(i,4))*sin(MDH(i,1)) cos(MDH(i,4))*sin(MDH(i,1)) cos(MDH(i,1)) cos(MDH(i,1))*MDH(i,3);
                    0 0 0 1];

                T_0_N(:,:,i) = T_0_ee;
            end
            T_0_ee(abs(T_0_ee)<10e-12) = 0;
        end

        function showRobot(~, AX, T_0_N)
            %showRobot  Belirtilen AX axesi uzerine T_0_N 3 boyutlu bir
            %           matrisin icerigini cizer

            cla(AX); % yeni cizgileri cizmeden once temizle
            view(AX, 45, 10); % sabit noktadan goruntu 
            axis(AX, 0.5*[-1 1 -1 1 -1 1]); %xlim ylim zlim
            grid(AX,'on');
            hold(AX, 'on');

            jnt = zeros(3,8);

            for i = 1:length(jnt)-1
                jnt(:,i+1) = T_0_N(1:3,4,i);
            end

            for i = 1:7
                line(AX, [jnt(1,i) jnt(1,i+1)], [jnt(2,i) jnt(2,i+1)],...
                    [jnt(3,i) jnt(3,i+1)]);
            end
            drawnow;
        end

        function updateVR(app)
            % VR ekrani uzerindeki jointlerin acilarini gunceller

            joints = ["J1_transform", "J2_transform",...
                      "J3_transform", "J4_transform",...
                      "J5_transform", "J6_transform"];
            app.world = setfield(app.world,joints(1),'rotation',[0 1 0 app.th(1)]);
            app.world = setfield(app.world,joints(2),'rotation',[0 1 0 -app.th(2)]);
            app.world = setfield(app.world,joints(3),'rotation',[0 1 0 -app.th(3)]);
            app.world = setfield(app.world,joints(4),'rotation',[0 1 0 -app.th(4)]);
            app.world = setfield(app.world,joints(5),'rotation',[0 1 0 app.th(5)]);
            app.world = setfield(app.world,joints(6),'rotation',[0 1 0 -app.th(6)]);
            % + - olmasi vrworld ekranda dogru yonde donmenin gosterilmesi ile ilgili
        end

        function updateObjects(app, currentObject, Pose)
            % Verilen currentObject haricinde tum giris bloklarindaki
            % degerleri gunceller

            Theta = round(rad2deg(app.th),3);
            switch nargin   % fonksiyon giris sayisi
                case 2      % eger 2 giris verilirse, Pose = eePose
                    Pose = round(app.eePose, 3);
            end
            
            if currentObject == "ForwardKinematics"
                app.xCartesian.Value = Pose(1,4);
                app.yCartesian.Value = Pose(2,4);
                app.zCartesian.Value = Pose(3,4);
            end

            if currentObject == "InverseCartesian"
                app.Theta1.Value = Theta(1);
                app.Theta2.Value = Theta(2);
                app.Theta3.Value = Theta(3);
                app.Theta4.Value = Theta(4);
                app.Theta5.Value = Theta(5);
                app.Theta6.Value = Theta(6);
            end

            if currentObject == "RPY" || currentObject == "none" 
                app.xCartesian.Value = Pose(1,4);
                app.yCartesian.Value = Pose(2,4);
                app.zCartesian.Value = Pose(3,4);
                app.Theta1.Value = Theta(1);
                app.Theta2.Value = Theta(2);
                app.Theta3.Value = Theta(3);
                app.Theta4.Value = Theta(4);
                app.Theta5.Value = Theta(5);
                app.Theta6.Value = Theta(6);
            end

            app.PoseTable.Data = Pose; % tabloyu guncelle
        end


        function [th_out, state] = optimal_Inverse(~, IK, th_current)
            % Ters K. cozumun hangisinin gerceklestirilebilir olmasina bakar
            % Bunu Ters K. cozumleri icerisinde th_current'dan en az fark
            % olacak sekilde bulur, fark eger pi/9'den fazla ise cozum
            % olarak kabul etmez ve th_out = theta_current olur
            % state: Ters K. cozum var ise true, yok ise false cikisi verir

            diffMatrix = abs(IK - th_current);
            S = sum(diffMatrix,2);
            index = S == min(S);
            if max(abs(IK(index,:) - th_current)) < pi/9
                th_out = IK(index,:);
                state = true;
            else
                th_out = th_current;
                state = false;
            end
        end

        function theta_out = motionPlannerJoint(~, t_total, th_0, th_f, Dth_0, Dth_f, D2th_0, D2th_f)
            % Kitapta verilen eklem uzayinda 5. dereceden polinom tum eklem
            % degiskenleri icin verir, parametreler:

            % t_total:  toplam sure
            % th_0:     baslangic acilari   th_f:       bitis acilari
            % Dth_0:    baslangic hizi      Dth_f:      bitis hizi
            % D2th_0:   baslangic ivmesi    D2th_f:     bitis ivmesi


            switch nargin % giris sayisi
                case 4      % 4 giris verildiyse (app, t_total, th_0, th_f)
                            % baslangic hiz ve ivmeleri 0 kabul eder
                    Dth_0 = 0; Dth_f = 0; D2th_0 = 0; D2th_f = 0;
                case 6      % 6 giris verilirse baslangic ve bitis ivmeleri 0 kabul eder
                    D2th_0 = 0; D2th_f = 0;
            end


            s0 = th_0;
            s1 = Dth_0;
            s2 = D2th_0/2;
            s3 = (20*(th_f-th_0) - (8*Dth_f+12*Dth_0).*t_total + (D2th_f - 3*D2th_0).*t_total.^2)./(2*t_total.^3);
            s4 = (30*(th_0-th_f) + (14*Dth_f+16*Dth_0).*t_total + (3*D2th_0 - D2th_f).*t_total.^2)./(2*t_total.^4);
            s5 = (12*(th_f-th_0) - 6*(Dth_f+Dth_0).*t_total - (D2th_0 - D2th_f).*t_total.^2)./(2*t_total.^5);

            t = 0:0.1:t_total;

            th_planned = s0' + s1'*t + s2'*t.^2 + s3'*t.^3 + s4'*t.^4 + s5'*t.^5;

            theta_out = struct();
            theta_out.time = t';
            theta_out.signals.values = th_planned';
        end
        
        function J = findJacobian(~, T)
            % T_0_N girise gore Jakobiyeni bulur, sadece 6R robot icin
            % gecerli
            Jv = zeros(3,6); Jw = zeros(3,6);
            for i = 1:6
                Jv(:,i) = cross(T(1:3,3,i),T(1:3,4,7)-T(1:3,4,i));
                Jw(:,i) = T(1:3,3,i);
            end
            J = [Jv;Jw];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Figure ilk acildiginda calistirilan kod
            close all;
            app.th = zeros(1,6);    % baslangicta theta'lar sifirlanir
            app.ax = app.UIAxes;    % app.UIAxes yerine app.ax kullanilir

            [app.eePose, T] = forwardKinematics(app, app.th);
            showRobot(app, app.ax, T);
            
            vrFig = figure;
            app.world = vrworld('Robot_VR.WRL');
            open(app.world); % world acilir
            vr.canvas(app.world); % vr ekrani acilir
            
            screenSize = get(0,'ScreenSize');
            x = screenSize(3);
            y = screenSize(4);
            app.UIFigure.Position = [x/20 y/6 x*10/20 y*2/3];
            vrFig.Position = [x*11/20 y/6 x*8/20 y*2/3];
            vrFig.MenuBar = 'none';

            updateVR(app);
            updateObjects(app, "none");
        end

        % Value changing function: Theta1
        function Theta1ValueChanging(app, event)
            % Forward Kinematics kisminda Theta 1 degistiginde calistirilan kod
            
            app.th(1) = deg2rad(event.Value); % degisen degeri radyana donustur ve app.th(1)'e ata

            [app.eePose, T] = forwardKinematics(app, app.th); % yeni app.th'ya Ileri K. uygula
            showRobot(app, app.ax, T); % plot ekraninda ciz
            updateVR(app); % VR ekrani guncelle
            updateObjects(app, "ForwardKinematics"); % ForwadKinematics haric tum objeleri yenile
        end

        % Value changing function: Theta2
        function Theta2ValueChanging(app, event)
            app.th(2) = deg2rad(event.Value);

            [app.eePose, T] = forwardKinematics(app, app.th);
            showRobot(app, app.ax, T);
            updateVR(app);
            updateObjects(app, "ForwardKinematics");
        end

        % Value changing function: Theta3
        function Theta3ValueChanging(app, event)
            app.th(3) = deg2rad(event.Value);

            [app.eePose, T] = forwardKinematics(app, app.th);
            showRobot(app, app.ax, T);
            updateVR(app);
            updateObjects(app, "ForwardKinematics");
        end

        % Value changing function: Theta4
        function Theta4ValueChanging(app, event)
            app.th(4) = deg2rad(event.Value);

            [app.eePose, T] = forwardKinematics(app, app.th);
            showRobot(app, app.ax, T);
            updateVR(app);
            updateObjects(app, "ForwardKinematics");
        end

        % Value changing function: Theta5
        function Theta5ValueChanging(app, event)
            app.th(5) = deg2rad(event.Value);

            [app.eePose, T] = forwardKinematics(app, app.th);
            showRobot(app, app.ax, T);
            updateVR(app);
            updateObjects(app, "ForwardKinematics");
        end

        % Value changing function: Theta6
        function Theta6ValueChanging(app, event)
            app.th(6) = deg2rad(event.Value);

            [app.eePose, T] = forwardKinematics(app, app.th);
            showRobot(app, app.ax, T);
            updateVR(app);
            updateObjects(app, "ForwardKinematics");
        end

        % Value changing function: xCartesian
        function xCartesianValueChanging(app, event)
            % bu fonksiyon Spinner basili tutulurken surekli calistirilian kod
            % KARTEZYEN UZAY, yani world frame'e gore yer degistirme

            Tnew = app.eePose;
            Tnew(1,4) = event.Value; % Tnew (eski app.eePose) icindeki x konumu yeni deger ile guncelle

            IK = robotIK(Tnew); % Ters K. uygula
            [app.th, state] = optimal_Inverse(app, IK, app.th); % en uygun ters kinematik cozumu bul

            if state % Ters K. cozum var ise
                app.SolutionLabel.Text = "IK Solution exists";
                [app.eePose, T] = forwardKinematics(app, app.th);
                showRobot(app, app.ax, T);
                updateVR(app);
                updateObjects(app, "InverseCartesian");
            else % Ters K. cozum yok ise
                app.SolutionLabel.Text = "No Solution exists";
                app.xCartesian.Enable = 'off'; % spinner enable off yap
            end 
        end

        % Value changed function: xCartesian
        function xCartesianValueChanged(app, event)
            % bu fonksiyon Spinner basili tutulup sadece biraktiktan sonra calistirilir
            
            % Ters K. cozumun var olmasi durumunda burada bise
            % degismiyor, eger yok ise Spinnnerin degerini eski app.eePose
            % olarak degistir ve enable on yap
            app.xCartesian.Value = round(app.eePose(1,4), 3);
            app.xCartesian.Enable = 'on';
        end

        % Value changing function: yCartesian
        function yCartesianValueChanging(app, event)
            Tnew = app.eePose;
            Tnew(2,4) = event.Value;

            IK = robotIK(Tnew);
            [app.th, state] = optimal_Inverse(app, IK, app.th);
            if state
                app.SolutionLabel.Text = "IK Solution exists";
                [app.eePose, T] = forwardKinematics(app, app.th);
                showRobot(app, app.ax, T);
                updateVR(app);
                updateObjects(app, "InverseCartesian");
            else
                app.SolutionLabel.Text = "No Solution exists";
                app.yCartesian.Enable = 'off';
            end
        end

        % Value changed function: yCartesian
        function yCartesianValueChanged(app, event)
            app.yCartesian.Value = round(app.eePose(2,4), 3);
            app.yCartesian.Enable = 'on';
        end

        % Value changing function: zCartesian
        function zCartesianValueChanging(app, event)
            Tnew = app.eePose;
            Tnew(3,4) = event.Value;

            IK = robotIK(Tnew);
            [app.th, state] = optimal_Inverse(app, IK, app.th);
            if state
                app.SolutionLabel.Text = "IK Solution exists";
                [app.eePose, T] = forwardKinematics(app, app.th);
                showRobot(app, app.ax, T);
                updateVR(app);
                updateObjects(app, "InverseCartesian");
            else
                app.SolutionLabel.Text = "No Solution exists";
                app.zCartesian.Enable = 'off';
            end
        end

        % Value changed function: zCartesian
        function zCartesianValueChanged(app, event)
            app.zCartesian.Value = round(app.eePose(3,4), 3);
            app.zCartesian.Enable = 'on';
        end

        % Value changing function: RxCartesian
        function RxCartesianValueChanging(app, event)
            % bu fonksiyon Spinner basili tutulurken calistirilan kod
            % KARTEZYEN UZAY, yani world frame'e gore donme
            
            gamma = deg2rad(event.Value);

            Rx = eul2rotm([0, 0, gamma]); % eul2rotm matlab hazir fonksiyon, ZYX donme matrisi olusturur
            Tnew = rotm2tform(Rx*app.eePose(1:3,1:3)); % Rx  eePose ile carp ve tform'a donustur (onden carpma = sabit koordinata gore)
            Tnew(1:3,4) = app.eePose(1:3,4); % sonra Tnew daki konumu ee konumu esitle (konumun sabit kalmasi icin)

            IK = robotIK(Tnew);
            [app.th, state] = optimal_Inverse(app, IK, app.th);
            if state
                app.SolutionLabel.Text = "IK Solution exists";
                [Tnew, T] = forwardKinematics(app, app.th); % burada Ileri K. app.eePose yerine Tnew'e esitlenir, cunku surekli
                                                            % app.eePose guncellenmesi istenmez
                showRobot(app, app.ax, T);
                updateVR(app);
                updateObjects(app, "RPY", Tnew);
            else
                app.SolutionLabel.Text = "No Solution exists";
                app.RxCartesian.Enable = 'off';
            end
        end

        % Value changed function: RxCartesian
        function RxCartesianValueChanged(app, event)
            % bu fonksiyon Spinner basili tutulup sadece biraktiktan sonra calistirilir

            % Spinner sifirlanir (cunku degisim artik bitti ve yeni degisim
            % 0'dan baslamasi lazim, enable on yapilir ve app.eePose guncellenir
            app.RxCartesian.Value = 0;
            app.RxCartesian.Enable = 'on';
            [app.eePose, ~] = forwardKinematics(app, app.th);
        end

        % Value changing function: RyCartesian
        function RyCartesianValueChanging(app, event)
            beta = deg2rad(event.Value);

            Ry = eul2rotm([0, beta, 0]);
            Tnew = rotm2tform(Ry*app.eePose(1:3,1:3));
            Tnew(1:3,4) = app.eePose(1:3,4);

            IK = robotIK(Tnew);
            [app.th, state] = optimal_Inverse(app, IK, app.th);
            if state
                app.SolutionLabel.Text = "IK Solution exists";
                [Tnew, T] = forwardKinematics(app, app.th);
                showRobot(app, app.ax, T);
                updateVR(app);
                updateObjects(app, "RPY", Tnew);
            else
                app.SolutionLabel.Text = "No Solution exists";
                app.RyCartesian.Enable = 'off';
            end
        end

        % Value changed function: RyCartesian
        function RyCartesianValueChanged(app, event)
            app.RyCartesian.Value = 0;
            app.RyCartesian.Enable = 'on';
            [app.eePose, ~] = forwardKinematics(app, app.th);
        end

        % Value changing function: RzCartesian
        function RzCartesianValueChanging(app, event)
            alpha = deg2rad(event.Value);

            Rz = eul2rotm([alpha, 0, 0]);
            Tnew = rotm2tform(Rz*app.eePose(1:3,1:3));
            Tnew(1:3,4) = app.eePose(1:3,4);

            IK = robotIK(Tnew);
            [app.th, state] = optimal_Inverse(app, IK, app.th);
            if state
                app.SolutionLabel.Text = "IK Solution exists";
                [Tnew, T] = forwardKinematics(app, app.th);
                showRobot(app, app.ax, T);
                updateVR(app);
                updateObjects(app, "RPY", Tnew);
            else
                app.SolutionLabel.Text = "No Solution exists";
                app.RzCartesian.Enable = 'off';
            end
        end

        % Value changed function: RzCartesian
        function RzCartesianValueChanged(app, event)
            app.RzCartesian.Value = 0;
            app.RzCartesian.Enable = 'on';
            [app.eePose, ~] = forwardKinematics(app, app.th);
        end

        % Value changing function: xJoint
        function xJointValueChanging(app, event)
            % bu fonksiyon Spinner basili tutulurken calistirilan kod
            % EKLEM UZAYINDA yer degistirme yapar
            Tpos = trvec2tform([event.Value, 0, 0]); % yer degistirme vektoru tforma donusur
            Tnew = app.eePose*Tpos; % arkadan carpilir (hareketli koordinata gore)

            IK = robotIK(Tnew);
            [app.th, state] = optimal_Inverse(app, IK, app.th);
            if state
                app.SolutionLabel.Text = "IK Solution exists";
                [Tnew, T] = forwardKinematics(app, app.th);
                showRobot(app, app.ax, T);
                updateVR(app);
                updateObjects(app, "RPY", Tnew);
            else
                app.SolutionLabel.Text = "No Solution exists";
                app.xJoint.Enable = 'off';
            end
        end

        % Value changed function: xJoint
        function xJointValueChanged(app, event)
            app.xJoint.Value = 0;
            app.xJoint.Enable = 'on';
            [app.eePose, ~] = forwardKinematics(app, app.th);
        end

        % Value changing function: yJoint
        function yJointValueChanging(app, event)
            Tpos = trvec2tform([0, event.Value, 0]);
            Tnew = app.eePose*Tpos;

            IK = robotIK(Tnew);
            [app.th, state] = optimal_Inverse(app, IK, app.th);
            if state
                app.SolutionLabel.Text = "IK Solution exists";
                [Tnew, T] = forwardKinematics(app, app.th);
                showRobot(app, app.ax, T);
                updateVR(app);
                updateObjects(app, "RPY", Tnew);
            else
                app.SolutionLabel.Text = "No Solution exists";
                app.yJoint.Enable = 'off';
            end
        end

        % Value changed function: yJoint
        function yJointValueChanged(app, event)
            app.yJoint.Value = 0;
            app.yJoint.Enable = 'on';
            [app.eePose, ~] = forwardKinematics(app, app.th);
        end

        % Value changing function: zJoint
        function zJointValueChanging(app, event)
            Tpos = trvec2tform([0, 0, event.Value]);
            Tnew = app.eePose*Tpos;

            IK = robotIK(Tnew);
            [app.th, state] = optimal_Inverse(app, IK, app.th);
            if state
                app.SolutionLabel.Text = "IK Solution exists";
                [Tnew, T] = forwardKinematics(app, app.th);
                showRobot(app, app.ax, T);
                updateVR(app);
                updateObjects(app, "RPY", Tnew);
            else
                app.SolutionLabel.Text = "No Solution exists";
                app.zJoint.Enable = 'off';
            end
        end

        % Value changed function: zJoint
        function zJointValueChanged(app, event)
            app.zJoint.Value = 0;
            app.zJoint.Enable = 'on';
            [app.eePose, ~] = forwardKinematics(app, app.th);
        end

        % Value changing function: RxJoint
        function RxJointValueChanging(app, event)
            % bu fonksiyon Spinner basili tutulurken calistirilan kod
            % EKLEM UZAYINDA donme yapar
            
            gamma = deg2rad(event.Value);

            Rx = eul2rotm([0, 0, gamma]); % eul2rotm ZYX donme girisleri donme matrisine donusturur
            Tnew = rotm2tform(app.eePose(1:3,1:3)*Rx); % arkadan carpma hareketli koordinata gore
            Tnew(1:3,4) = app.eePose(1:3,4); % konum sabit kalabilmesi icin

            IK = robotIK(Tnew);
            [app.th, state] = optimal_Inverse(app, IK, app.th);
            if state
                app.SolutionLabel.Text = "IK Solution exists";
                [Tnew, T] = forwardKinematics(app, app.th);
                showRobot(app, app.ax, T);
                updateVR(app);
                updateObjects(app, "RPY", Tnew);
            else
                app.SolutionLabel.Text = "No Solution exists";
                app.RxJoint.Enable = 'off';
            end
        end

        % Value changed function: RxJoint
        function RxJointValueChanged(app, event)
            app.RxJoint.Value = 0;
            app.RxJoint.Enable = 'on';
            [app.eePose, ~] = forwardKinematics(app, app.th);
        end

        % Value changing function: RyJoint
        function RyJointValueChanging(app, event)
            beta = deg2rad(event.Value);

            Ry = eul2rotm([0, beta, 0]);
            Tnew = rotm2tform(app.eePose(1:3,1:3)*Ry);
            Tnew(1:3,4) = app.eePose(1:3,4);

            IK = robotIK(Tnew);
            [app.th, state] = optimal_Inverse(app, IK, app.th);
            if state
                app.SolutionLabel.Text = "IK Solution exists";
                [Tnew, T] = forwardKinematics(app, app.th);
                showRobot(app, app.ax, T);
                updateVR(app);
                updateObjects(app, "RPY", Tnew);
            else
                app.SolutionLabel.Text = "No Solution exists";
                app.RyJoint.Enable = 'off';
            end
        end

        % Value changed function: RyJoint
        function RyJointValueChanged(app, event)
            app.RyJoint.Value = 0;
            app.RyJoint.Enable = 'on';
            [app.eePose, ~] = forwardKinematics(app, app.th);
        end

        % Value changing function: RzJoint
        function RzJointValueChanging(app, event)
            alpha = deg2rad(event.Value);

            Rz = eul2rotm([alpha, 0, 0]);
            Tnew = rotm2tform(app.eePose(1:3,1:3)*Rz);
            Tnew(1:3,4) = app.eePose(1:3,4);

            IK = robotIK(Tnew);
            [app.th, state] = optimal_Inverse(app, IK, app.th);
            if state
                app.SolutionLabel.Text = "IK Solution exists";
                [Tnew, T] = forwardKinematics(app, app.th);
                showRobot(app, app.ax, T);
                updateVR(app);
                updateObjects(app, "RPY", Tnew);
            else
                app.SolutionLabel.Text = "No Solution exists";
                app.RzJoint.Enable = 'off';
            end
        end

        % Value changed function: RzJoint
        function RzJointValueChanged(app, event)
            app.RzJoint.Value = 0;
            app.RzJoint.Enable = 'on';
            [app.eePose, ~] = forwardKinematics(app, app.th);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FK_Panel = uipanel(app.UIFigure);
            app.FK_Panel.Title = 'Forward Kinematics';
            app.Theta1SpinnerLabel = uilabel(app.FK_Panel);
            app.Theta1SpinnerLabel.Text = 'Theta 1';
            app.Theta1 = uispinner(app.FK_Panel);
            app.Theta1.Step = 2;
            app.Theta1.ValueChangingFcn = createCallbackFcn(app, @Theta1ValueChanging, true);
            app.Theta1.Limits = [-360 360];
            app.Theta2SpinnerLabel = uilabel(app.FK_Panel);
            app.Theta2SpinnerLabel.Text = 'Theta 2';
            app.Theta2 = uispinner(app.FK_Panel);
            app.Theta2.Step = 2;
            app.Theta2.ValueChangingFcn = createCallbackFcn(app, @Theta2ValueChanging, true);
            app.Theta2.Limits = [-360 360];
            app.Theta3SpinnerLabel = uilabel(app.FK_Panel);
            app.Theta3SpinnerLabel.Text = 'Theta 3';
            app.Theta3 = uispinner(app.FK_Panel);
            app.Theta3.Step = 2;
            app.Theta3.ValueChangingFcn = createCallbackFcn(app, @Theta3ValueChanging, true);
            app.Theta3.Limits = [-360 360];
            app.Theta4SpinnerLabel = uilabel(app.FK_Panel);
            app.Theta4SpinnerLabel.Text = 'Theta 4';
            app.Theta4 = uispinner(app.FK_Panel);
            app.Theta4.Step = 2;
            app.Theta4.ValueChangingFcn = createCallbackFcn(app, @Theta4ValueChanging, true);
            app.Theta4.Limits = [-360 360];
            app.Theta5SpinnerLabel = uilabel(app.FK_Panel);
            app.Theta5SpinnerLabel.Text = 'Theta 5';
            app.Theta5 = uispinner(app.FK_Panel);
            app.Theta5.Step = 2;
            app.Theta5.ValueChangingFcn = createCallbackFcn(app, @Theta5ValueChanging, true);
            app.Theta5.Limits = [-360 360];
            app.Theta6SpinnerLabel = uilabel(app.FK_Panel);
            app.Theta6SpinnerLabel.Text = 'Theta 6';
            app.Theta6 = uispinner(app.FK_Panel);
            app.Theta6.Step = 2;
            app.Theta6.ValueChangingFcn = createCallbackFcn(app, @Theta6ValueChanging, true);
            app.Theta6.Limits = [-360 360];
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CartesianSpace = uitab(app.TabGroup);
            app.CartesianSpace.Title = 'Cartesian Space';
            app.xSpinnerLabel = uilabel(app.CartesianSpace);
            app.xSpinnerLabel.Text = 'x';
            app.xCartesian = uispinner(app.CartesianSpace);
            app.xCartesian.Step = 0.005;
            app.xCartesian.ValueChangingFcn = createCallbackFcn(app, @xCartesianValueChanging, true);
            app.xCartesian.ValueChangedFcn = createCallbackFcn(app, @xCartesianValueChanged, true);
            app.ySpinnerLabel = uilabel(app.CartesianSpace);
            app.ySpinnerLabel.Text = 'y';
            app.yCartesian = uispinner(app.CartesianSpace);
            app.yCartesian.Step = 0.005;
            app.yCartesian.ValueChangingFcn = createCallbackFcn(app, @yCartesianValueChanging, true);
            app.yCartesian.ValueChangedFcn = createCallbackFcn(app, @yCartesianValueChanged, true);
            app.zSpinnerLabel = uilabel(app.CartesianSpace);
            app.zSpinnerLabel.Text = 'z';
            app.zCartesian = uispinner(app.CartesianSpace);
            app.zCartesian.Step = 0.005;
            app.zCartesian.ValueChangingFcn = createCallbackFcn(app, @zCartesianValueChanging, true);
            app.zCartesian.ValueChangedFcn = createCallbackFcn(app, @zCartesianValueChanged, true);
            app.YawxSpinnerLabel = uilabel(app.CartesianSpace);
            app.YawxSpinnerLabel.Text = 'Yaw x';
            app.RxCartesian = uispinner(app.CartesianSpace);
            app.RxCartesian.Step = 2;
            app.RxCartesian.ValueChangingFcn = createCallbackFcn(app, @RxCartesianValueChanging, true);
            app.RxCartesian.ValueChangedFcn = createCallbackFcn(app, @RxCartesianValueChanged, true);
            app.PitchySpinnerLabel = uilabel(app.CartesianSpace);
            app.PitchySpinnerLabel.Text = 'Pitch y';
            app.RyCartesian = uispinner(app.CartesianSpace);
            app.RyCartesian.Step = 2;
            app.RyCartesian.ValueChangingFcn = createCallbackFcn(app, @RyCartesianValueChanging, true);
            app.RyCartesian.ValueChangedFcn = createCallbackFcn(app, @RyCartesianValueChanged, true);
            app.RollzSpinnerLabel = uilabel(app.CartesianSpace);
            app.RollzSpinnerLabel.Text = 'Roll z';
            app.RzCartesian = uispinner(app.CartesianSpace);
            app.RzCartesian.Step = 2;
            app.RzCartesian.ValueChangingFcn = createCallbackFcn(app, @RzCartesianValueChanging, true);
            app.RzCartesian.ValueChangedFcn = createCallbackFcn(app, @RzCartesianValueChanged, true);
            app.JointSpace = uitab(app.TabGroup);
            app.JointSpace.Title = 'Joint space';
            app.JointxLabel = uilabel(app.JointSpace);
            app.JointxLabel.Text = 'Joint x';
            app.xJoint = uispinner(app.JointSpace);
            app.xJoint.Step = 0.001;
            app.xJoint.ValueChangingFcn = createCallbackFcn(app, @xJointValueChanging, true);
            app.xJoint.ValueChangedFcn = createCallbackFcn(app, @xJointValueChanged, true);
            app.JointySpinnerLabel = uilabel(app.JointSpace);
            app.JointySpinnerLabel.Text = 'Joint y';
            app.yJoint = uispinner(app.JointSpace);
            app.yJoint.Step = 0.001;
            app.yJoint.ValueChangingFcn = createCallbackFcn(app, @yJointValueChanging, true);
            app.yJoint.ValueChangedFcn = createCallbackFcn(app, @yJointValueChanged, true);
            app.JointzSpinnerLabel = uilabel(app.JointSpace);
            app.JointzSpinnerLabel.Text = 'Joint z';
            app.zJoint = uispinner(app.JointSpace);
            app.zJoint.Step = 0.001;
            app.zJoint.ValueChangingFcn = createCallbackFcn(app, @zJointValueChanging, true);
            app.zJoint.ValueChangedFcn = createCallbackFcn(app, @zJointValueChanged, true);
            app.JointRxSpinnerLabel = uilabel(app.JointSpace);
            app.JointRxSpinnerLabel.Text = 'Joint Rx';
            app.RxJoint = uispinner(app.JointSpace);
            app.RxJoint.Step = 2;
            app.RxJoint.ValueChangingFcn = createCallbackFcn(app, @RxJointValueChanging, true);
            app.RxJoint.ValueChangedFcn = createCallbackFcn(app, @RxJointValueChanged, true);
            app.JointRySpinnerLabel = uilabel(app.JointSpace);
            app.JointRySpinnerLabel.Text = 'Joint Ry';
            app.RyJoint = uispinner(app.JointSpace);
            app.RyJoint.Step = 2;
            app.RyJoint.ValueChangingFcn = createCallbackFcn(app, @RyJointValueChanging, true);
            app.RyJoint.ValueChangedFcn = createCallbackFcn(app, @RyJointValueChanged, true);
            app.JointRzSpinnerLabel = uilabel(app.JointSpace);
            app.JointRzSpinnerLabel.Text = 'Joint Rz';
            app.RzJoint = uispinner(app.JointSpace);
            app.RzJoint.Step = 2;
            app.RzJoint.ValueChangingFcn = createCallbackFcn(app, @RzJointValueChanging, true);
            app.RzJoint.ValueChangedFcn = createCallbackFcn(app, @RzJointValueChanged, true);
            app.PoseTable = uitable(app.UIFigure);
            app.PoseTable.ColumnName = '';
            app.PoseTable.RowName = {};
            app.PoseLabel = uilabel(app.UIFigure);
            app.PoseLabel.Text = 'End Effector Pose';
            app.SolutionLabel = uilabel(app.UIFigure);
            app.SolutionLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Kinematics

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end